classdef PAS_SIMPLE_FUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WindSpeedEditField    matlab.ui.control.NumericEditField
        WindSpeedLabel        matlab.ui.control.Label
        AVDASI2PressureSystemWingsLabel  matlab.ui.control.Label
        BusyLamp              matlab.ui.control.Lamp
        BusyLampLabel         matlab.ui.control.Label
        RawDataPanel          matlab.ui.container.Panel
        PS06EditField         matlab.ui.control.NumericEditField
        PS06EditFieldLabel    matlab.ui.control.Label
        PS12EditField         matlab.ui.control.NumericEditField
        PS12EditFieldLabel    matlab.ui.control.Label
        PS05EditField         matlab.ui.control.NumericEditField
        PS05EditFieldLabel    matlab.ui.control.Label
        PS11EditField         matlab.ui.control.NumericEditField
        PS11EditFieldLabel    matlab.ui.control.Label
        PS04EditField         matlab.ui.control.NumericEditField
        PS04EditFieldLabel    matlab.ui.control.Label
        PS10EditField         matlab.ui.control.NumericEditField
        PS10EditFieldLabel    matlab.ui.control.Label
        PS03EditField         matlab.ui.control.NumericEditField
        PS03EditFieldLabel    matlab.ui.control.Label
        PS09EditField         matlab.ui.control.NumericEditField
        PS09EditFieldLabel    matlab.ui.control.Label
        PS02EditField         matlab.ui.control.NumericEditField
        PS02EditFieldLabel    matlab.ui.control.Label
        PS08EditField         matlab.ui.control.NumericEditField
        PS08EditFieldLabel    matlab.ui.control.Label
        PS07EditField         matlab.ui.control.NumericEditField
        PS07EditFieldLabel    matlab.ui.control.Label
        PS01EditField         matlab.ui.control.NumericEditField
        PS01EditFieldLabel    matlab.ui.control.Label
        FileSetupPanel        matlab.ui.container.Panel
        StartLogButton        matlab.ui.control.Button
        FileTextArea          matlab.ui.control.TextArea
        FileTextAreaLabel     matlab.ui.control.Label
        csvLabel              matlab.ui.control.Label
        SampleTextArea        matlab.ui.control.TextArea
        SampleTextAreaLabel   matlab.ui.control.Label
        sLabel                matlab.ui.control.Label
        FlapTextArea          matlab.ui.control.TextArea
        AoATextArea_2Label_2  matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        AoATextArea           matlab.ui.control.TextArea
        AoATextArea_2Label    matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        DivisionDropDown      matlab.ui.control.DropDown
        DivisionLabel         matlab.ui.control.Label
        CompanyDropDown       matlab.ui.control.DropDown
        CompanyDropDownLabel  matlab.ui.control.Label
        ConnectionSetupPanel  matlab.ui.container.Panel
        StartSerial           matlab.ui.control.Button
        ConnectedLamp         matlab.ui.control.Lamp
        Button                matlab.ui.control.Button
        COMPortDropDown       matlab.ui.control.DropDown
        COMPortDropDownLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        AerofoilPath % Description
        PointsPath
        point_coordinates
        normal_vectors
        aerofoil_points
        SerialDevice
        FileName
        Speed
        density=1.225;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            serialport=serialportlist;
            app.COMPortDropDown.Items=serialport;
            clear SerialDevice;
            app.ConnectedLamp.Color=[1.00,0.00,0.00];
        end

        % Value changed function: CompanyDropDown
        function CompanyDropDownValueChanged(app, event)
            AOAvalue = app.AoATextArea.Value;
            Companyvalue = app.CompanyDropDown.Value;
            Flapvalue = app.FlapTextArea.Value;
            Divisionvalue = app.DivisionDropDown.Value;
            FileDisplayName=strcat(Companyvalue,'-',Divisionvalue,'_AOA_',AOAvalue,'_FLAP_',Flapvalue);
            app.FileName=strcat(FileDisplayName,'.csv');
            app.FileTextArea.Value=FileDisplayName;
        end

        % Value changed function: DivisionDropDown
        function DivisionDropDownValueChanged(app, event)
            AOAvalue = app.AoATextArea.Value;
            Companyvalue = app.CompanyDropDown.Value;
            Flapvalue = app.FlapTextArea.Value;
            Divisionvalue = app.DivisionDropDown.Value;
            FileDisplayName=strcat(Companyvalue,'-',Divisionvalue,'_AOA_',AOAvalue,'_FLAP_',Flapvalue);
            app.FileName=strcat(FileDisplayName,'.csv');
            app.FileTextArea.Value=FileDisplayName;
        end

        % Value changed function: AoATextArea
        function AoATextAreaValueChanged(app, event)
            AOAvalue = app.AoATextArea.Value;
            Companyvalue = app.CompanyDropDown.Value;
            Flapvalue = app.FlapTextArea.Value;
            Divisionvalue = app.DivisionDropDown.Value;
            FileDisplayName=strcat(Companyvalue,'-',Divisionvalue,'_AOA_',AOAvalue,'_FLAP_',Flapvalue);
            app.FileName=strcat(FileDisplayName,'.csv');
            app.FileTextArea.Value=FileDisplayName;
        end

        % Value changed function: FlapTextArea
        function FlapTextAreaValueChanged(app, event)
            AOAvalue = app.AoATextArea.Value;
            Companyvalue = app.CompanyDropDown.Value;
            Flapvalue = app.FlapTextArea.Value;
            Divisionvalue = app.DivisionDropDown.Value;
            FileDisplayName=strcat(Companyvalue,'-',Divisionvalue,'_AOA_',AOAvalue,'_FLAP_',Flapvalue);
            app.FileName=strcat(FileDisplayName,'.csv');
            app.FileTextArea.Value=FileDisplayName;
        end

        % Button pushed function: StartLogButton
        function StartLogButtonPushed(app, event)
            app.AoATextArea.Enable='off';
            app.FlapTextArea.Enable='off';
            app.CompanyDropDown.Enable='off';
            app.SampleTextArea.Enable='off';
            app.DivisionDropDown.Enable='off';
            app.StartLogButton.Enable='off';
            app.BusyLamp.Color=[0.72,0.27,1.00];

            LoggingTime=str2double((app.SampleTextArea.Value));
            interval=200;
            numberofreads=ceil((1000/interval)*LoggingTime);
            ii=1;
            startDateTime = string(datetime('now', 'Format', 'yyyy-MM-dd_HH:mm:ss'));
            Log=[startDateTime;"-";"-";"-";"-";"-";"-";"-";"-";"-";"-";"-"];
            SensorNames=["P1";"P2";"P3";"P4";"P5";"P6";"P7";"P8";"P9";"P10";"P11";"P12"];
            Log=cat(2,Log,SensorNames);
            coeff_line=plot(app.UIAxes,[1:12],nan(1,12),'x-','MarkerSize', 10, 'MarkerEdgeColor',[0.8500 0.3250 0.0980],'LineWidth',2,'Color',[0.6350 0.0780 0.1840]);
            for ii=1:numberofreads
                buffer=readline(app.SerialDevice);
                CellData=strsplit(buffer,',');
                PressureData=str2double(CellData);
                PressureData=100*((PressureData .* (5/1023))-2.5);

                Coefficients=PressureData./((app.density*(app.Speed^2))/2);

                app.PS01EditField.Value= PressureData(1);
                app.PS02EditField.Value= PressureData(2);
                app.PS03EditField.Value= PressureData(3);
                app.PS04EditField.Value= PressureData(4);
                app.PS05EditField.Value= PressureData(5);
                app.PS06EditField.Value= PressureData(6);
                app.PS07EditField.Value= PressureData(7);
                app.PS08EditField.Value= PressureData(8);
                app.PS09EditField.Value= PressureData(9);
                app.PS10EditField.Value= PressureData(10);
                app.PS11EditField.Value= PressureData(11);
                app.PS12EditField.Value= PressureData(12);

                set(coeff_line,'Ydata',Coefficients);
                PressureData=PressureData';
                Log=cat(2,Log,PressureData);
                pause(200/1000);
            end
            writematrix(Log,char(app.FileName))
            app.AoATextArea.Enable='on';
            app.FlapTextArea.Enable='on';
            app.CompanyDropDown.Enable='on';
            app.SampleTextArea.Enable='on';
            app.DivisionDropDown.Enable='on';
            app.StartLogButton.Enable='on';
            app.BusyLamp.Color=[0.80,0.80,0.80];
        end

        % Button pushed function: StartSerial
        function StartSerialButtonPushed(app, event)
            app.BusyLamp.Color=[0.72,0.27,1.00];
            app.COMPortDropDown.Enable='off';
            app.Button.Enable='off';
            app.StartSerial.BackgroundColor=[0.96,0.96,0.96];
            app.StartSerial.Enable='off';
            selectedPort=app.COMPortDropDown.Value;
            app.SerialDevice=serialport(selectedPort,19200);
            pause(5);
            app.ConnectedLamp.Color=[0.00,1.00,0.00];
            
            app.StartLogButton.Enable='on';
            app.SampleTextArea.Enable='on';
            app.sLabel.Enable='on';
            app.FlapTextArea.Enable='on';
            app.Label_4.Enable='on';
            app.AoATextArea.Enable='on';
            app.Label_3.Enable='on';
            app.DivisionDropDown.Enable='on';
            app.CompanyDropDown.Enable='on';
            app.BusyLamp.Color=[0.80,0.80,0.80];

            app.StartSerial.Interruptible='on';
            coeff_line=plot(app.UIAxes,[1:12],nan(1,12),'x-','MarkerSize', 10, 'MarkerEdgeColor',[0.8500 0.3250 0.0980],'LineWidth',2,'Color',[0.6350 0.0780 0.1840]);
            while(1)
                app.Speed=app.WindSpeedEditField.Value;
                buffer=readline(app.SerialDevice);
                CellData=strsplit(buffer,',');
                PressureData=str2double(CellData);
                PressureData=1000*(((5/1023)*PressureData)-2.5);

                Coefficients=PressureData./((app.density*(app.Speed^2))/2);

                app.PS01EditField.Value= PressureData(1);
                app.PS02EditField.Value= PressureData(2);
                app.PS03EditField.Value= PressureData(3);
                app.PS04EditField.Value= PressureData(4);
                app.PS05EditField.Value= PressureData(5);
                app.PS06EditField.Value= PressureData(6);
                app.PS07EditField.Value= PressureData(7);
                app.PS08EditField.Value= PressureData(8);
                app.PS09EditField.Value= PressureData(9);
                app.PS10EditField.Value= PressureData(10);
                app.PS11EditField.Value= PressureData(11);
                app.PS12EditField.Value= PressureData(12);
                
                set(coeff_line,'Ydata',Coefficients);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Coefficient of Pressure')
            xlabel(app.UIAxes, 'Pressure Sensor Number')
            ylabel(app.UIAxes, 'Coefficient of Pressure')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 13];
            app.UIAxes.YLim = [-1 1.75];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.ConnectionSetupPanel = uipanel(app.UIFigure);
            app.ConnectionSetupPanel.Title = 'Connection Setup';
            app.COMPortDropDownLabel = uilabel(app.ConnectionSetupPanel);
            app.COMPortDropDownLabel.Text = 'COM Port:';
            app.COMPortDropDown = uidropdown(app.ConnectionSetupPanel);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.Button = uibutton(app.ConnectionSetupPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '↻';
            app.ConnectedLamp = uilamp(app.ConnectionSetupPanel);
            app.StartSerial = uibutton(app.ConnectionSetupPanel, 'push');
            app.StartSerial.ButtonPushedFcn = createCallbackFcn(app, @StartSerialButtonPushed, true);
            app.StartSerial.Text = '▶';
            app.FileSetupPanel = uipanel(app.UIFigure);
            app.FileSetupPanel.Title = 'File Setup';
            app.CompanyDropDownLabel = uilabel(app.FileSetupPanel);
            app.CompanyDropDownLabel.Text = 'Company:';
            app.CompanyDropDown = uidropdown(app.FileSetupPanel);
            app.CompanyDropDown.Items = {'-', 'A', 'B'};
            app.CompanyDropDown.ValueChangedFcn = createCallbackFcn(app, @CompanyDropDownValueChanged, true);
            app.CompanyDropDown.Enable = 'off';
            app.CompanyDropDown.Value = '-';
            app.DivisionLabel = uilabel(app.FileSetupPanel);
            app.DivisionLabel.Text = 'Division:';
            app.DivisionDropDown = uidropdown(app.FileSetupPanel);
            app.DivisionDropDown.Items = {'-', 'PW', 'SW'};
            app.DivisionDropDown.ValueChangedFcn = createCallbackFcn(app, @DivisionDropDownValueChanged, true);
            app.DivisionDropDown.Enable = 'off';
            app.DivisionDropDown.Value = '-';
            app.Label_3 = uilabel(app.FileSetupPanel);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '°';
            app.AoATextArea_2Label = uilabel(app.FileSetupPanel);
            app.AoATextArea_2Label.Text = 'AoA:';
            app.AoATextArea = uitextarea(app.FileSetupPanel);
            app.AoATextArea.ValueChangedFcn = createCallbackFcn(app, @AoATextAreaValueChanged, true);
            app.AoATextArea.Enable = 'off';
            app.AoATextArea.Tooltip = {'Remember to include "+" or "-"'};
            app.AoATextArea.Placeholder = '-';
            app.Label_4 = uilabel(app.FileSetupPanel);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '°';
            app.AoATextArea_2Label_2 = uilabel(app.FileSetupPanel);
            app.AoATextArea_2Label_2.Text = 'Flap:';
            app.FlapTextArea = uitextarea(app.FileSetupPanel);
            app.FlapTextArea.ValueChangedFcn = createCallbackFcn(app, @FlapTextAreaValueChanged, true);
            app.FlapTextArea.Enable = 'off';
            app.FlapTextArea.Placeholder = '-';
            app.sLabel = uilabel(app.FileSetupPanel);
            app.sLabel.Enable = 'off';
            app.sLabel.Text = 's';
            app.SampleTextAreaLabel = uilabel(app.FileSetupPanel);
            app.SampleTextAreaLabel.Text = 'Sample:';
            app.SampleTextArea = uitextarea(app.FileSetupPanel);
            app.SampleTextArea.Enable = 'off';
            app.SampleTextArea.Placeholder = '-';
            app.csvLabel = uilabel(app.FileSetupPanel);
            app.csvLabel.Text = {'.csv'; ''};
            app.FileTextAreaLabel = uilabel(app.FileSetupPanel);
            app.FileTextAreaLabel.Text = 'File:';
            app.FileTextArea = uitextarea(app.FileSetupPanel);
            app.FileTextArea.Editable = 'off';
            app.StartLogButton = uibutton(app.FileSetupPanel, 'push');
            app.StartLogButton.ButtonPushedFcn = createCallbackFcn(app, @StartLogButtonPushed, true);
            app.StartLogButton.Enable = 'off';
            app.StartLogButton.Text = 'Start Log';
            app.RawDataPanel = uipanel(app.UIFigure);
            app.RawDataPanel.Title = 'Raw Data';
            app.PS01EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS01EditFieldLabel.Text = 'PS 01:';
            app.PS01EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS01EditField.AllowEmpty = 'on';
            app.PS01EditField.Editable = 'off';
            app.PS01EditField.Value = [];
            app.PS07EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS07EditFieldLabel.Text = 'PS 07:';
            app.PS07EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS07EditField.AllowEmpty = 'on';
            app.PS07EditField.Editable = 'off';
            app.PS07EditField.Value = [];
            app.PS08EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS08EditFieldLabel.Text = 'PS 08:';
            app.PS08EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS08EditField.AllowEmpty = 'on';
            app.PS08EditField.Editable = 'off';
            app.PS08EditField.Value = [];
            app.PS02EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS02EditFieldLabel.Text = 'PS 02:';
            app.PS02EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS02EditField.AllowEmpty = 'on';
            app.PS02EditField.Editable = 'off';
            app.PS02EditField.Value = [];
            app.PS09EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS09EditFieldLabel.Text = 'PS 09:';
            app.PS09EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS09EditField.AllowEmpty = 'on';
            app.PS09EditField.Editable = 'off';
            app.PS09EditField.Value = [];
            app.PS03EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS03EditFieldLabel.Text = 'PS 03:';
            app.PS03EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS03EditField.AllowEmpty = 'on';
            app.PS03EditField.Editable = 'off';
            app.PS03EditField.Value = [];
            app.PS10EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS10EditFieldLabel.Text = 'PS 10:';
            app.PS10EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS10EditField.AllowEmpty = 'on';
            app.PS10EditField.Editable = 'off';
            app.PS10EditField.Value = [];
            app.PS04EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS04EditFieldLabel.Text = 'PS 04:';
            app.PS04EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS04EditField.AllowEmpty = 'on';
            app.PS04EditField.Editable = 'off';
            app.PS04EditField.Value = [];
            app.PS11EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS11EditFieldLabel.Text = 'PS 11:';
            app.PS11EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS11EditField.AllowEmpty = 'on';
            app.PS11EditField.Editable = 'off';
            app.PS11EditField.Value = [];
            app.PS05EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS05EditFieldLabel.Text = 'PS 05:';
            app.PS05EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS05EditField.AllowEmpty = 'on';
            app.PS05EditField.Editable = 'off';
            app.PS05EditField.Value = [];
            app.PS12EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS12EditFieldLabel.Text = 'PS 12:';
            app.PS12EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS12EditField.AllowEmpty = 'on';
            app.PS12EditField.Editable = 'off';
            app.PS12EditField.Value = [];
            app.PS06EditFieldLabel = uilabel(app.RawDataPanel);
            app.PS06EditFieldLabel.Text = 'PS 06:';
            app.PS06EditField = uieditfield(app.RawDataPanel, 'numeric');
            app.PS06EditField.AllowEmpty = 'on';
            app.PS06EditField.Editable = 'off';
            app.PS06EditField.Value = [];
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy:';
            app.BusyLamp = uilamp(app.UIFigure);
            app.AVDASI2PressureSystemWingsLabel = uilabel(app.UIFigure);
            app.AVDASI2PressureSystemWingsLabel.Text = 'AVDASI 2 - Pressure System (Wings)';
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'Wind Speed:';
            app.WindSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindSpeedEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PAS_SIMPLE_FUS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end